classdef bank_system < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        withdrawButton         matlab.ui.control.Button
        depositeButton         matlab.ui.control.Button
        balanceEditFieldLabel  matlab.ui.control.Label
        balanceEditField       matlab.ui.control.NumericEditField
        rmbEditFieldLabel      matlab.ui.control.Label
        rmbEditField           matlab.ui.control.NumericEditField
    end

    
    methods (Access = private)


    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.balanceEditField.Value = 500000 ;
            app.rmbEditField.Value = 0;
            [y, fs]=audioread('Doki Doki Literature Club_ OST - Doki Doki Literat(MP3_128K).mp3');
            sound(y,fs);
        end

        % Button pushed function: withdrawButton
        function withdrawButtonPushed(app, event)
            value = app.rmbEditField.Value ;
            if value <100 || value >30000
                msgbox('value must be between 100 and 30000','Invalid entry');
            elseif value < app.balanceEditField.Value
                app.balanceEditField.Value= app.balanceEditField.Value - value;
            else
                msgbox('qq~~ you are broke')
            end
        end

        % Button pushed function: depositeButton
        function depositeButtonPushed(app, event)
            value = app.rmbEditField.Value ;
            app.balanceEditField.Value= app.balanceEditField.Value + value;
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)

            delete(app)
            clear sound;
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.withdrawButton = uibutton(app.UIFigure, 'push');
            app.withdrawButton.ButtonPushedFcn = createCallbackFcn(app, @withdrawButtonPushed, true);
            app.withdrawButton.Text = 'withdraw';
            app.depositeButton = uibutton(app.UIFigure, 'push');
            app.depositeButton.ButtonPushedFcn = createCallbackFcn(app, @depositeButtonPushed, true);
            app.depositeButton.Text = 'deposite';
            app.balanceEditFieldLabel = uilabel(app.UIFigure);
            app.balanceEditFieldLabel.Text = 'balance';
            app.balanceEditField = uieditfield(app.UIFigure, 'numeric');
            app.balanceEditField.ValueDisplayFormat = '%.0f';
            app.balanceEditField.Value = 500000;
            app.rmbEditFieldLabel = uilabel(app.UIFigure);
            app.rmbEditFieldLabel.Text = 'rmb';
            app.rmbEditField = uieditfield(app.UIFigure, 'numeric');
            app.rmbEditField.ValueDisplayFormat = '%.0f';
        end
    end

    methods (Access = public)

        % Construct app
        function app = bank_system

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end